classdef Sound_filter_with_fft < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                 matlab.ui.Figure
        OutputButton             matlab.ui.control.Button
        OriginalButton           matlab.ui.control.Button
        SoundFilesDropDown       matlab.ui.control.DropDown
        SoundFilesDropDownLabel  matlab.ui.control.Label
        UIAxes5                  matlab.ui.control.UIAxes
        UIAxes4                  matlab.ui.control.UIAxes
        UIAxes3                  matlab.ui.control.UIAxes
        UIAxes2                  matlab.ui.control.UIAxes
        UIAxes                   matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        Chosen = 0 % dropdown menu selection
        DataIn = [0 0] % input matrix
        Fs = 48000 % smapling frequency
        DataOut=0    %stereo output in freq
        DataOut_Left=0 % left side output in freq
        DataOut_Right=0 % right side output in freq
        DataOut1 = 0 % stereo output in time
        T = 0 % duration of the sound file
        Filtered=0 % filtered out noise
        t=0 % time vector for plotting
        freqs=0 % frequency vector for filtering
        finput=0 %fourier transformed input
        
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Value changed function: SoundFilesDropDown
        function SoundFilesDropDownValueChanged(app, event)
            app.Chosen = app.SoundFilesDropDown.Value;
            
            switch app.Chosen
                case 'Armstrong'
                    [app.DataIn,app.Fs] = audioread('Armstrong.wav')
                case 'Asuman Pansuman'
                    [app.DataIn,app.Fs] = audioread('Asuman.wav')
                case 'PhilipGlass'
                    [app.DataIn,app.Fs] = audioread('PhilipGlass.wav')
            end
        end

        % Button pushed function: OriginalButton
        function OriginalButtonPushed(app, event)
  
            sound(app.DataIn,app.Fs)
            
            app.finput = fft(app.DataIn);
            app.freqs = linspace(0, app.Fs, length(app.finput)); %frequency vector
            
            Fc1 = 100;  % low-frequency cutoff
            Fc2 = 3000;  % high-frequency cutoff
            
            % bandpass filter(filter out indexes lower than Fc1 and higher than Fc2
            app.DataOut_Left = app.finput(:,1);
            app.DataOut_Left(app.freqs < Fc1 | app.freqs > Fc2) = 0;
            app.DataOut_Right = app.finput(:,2);
            app.DataOut_Right(app.freqs < Fc1 | app.freqs > Fc2) = 0;
            
            app.DataOut=zeros([length(app.DataIn) 2]);
            app.DataOut(:,1)=app.DataOut_Left;
            app.DataOut(:,2)=app.DataOut_Right;
            
            % Perform iFFT to obtain time domain signal
            app.DataOut1 = ifft(app.DataOut);


            app.Filtered=app.DataIn-app.DataOut1
            plot(app.UIAxes,app.DataIn,'r')
            
            plot(app.UIAxes2, real(app.DataOut1),'b')
            
            app.T=(length(app.DataIn)-1)/app.Fs;
            app.t= linspace(0,app.T,length(app.DataIn(:,2)));
            hold( app.UIAxes3, 'on' )
            plot(app.UIAxes3,app.t,app.Filtered(:,2),'g')
            plot(app.UIAxes3,app.t,app.DataOut1(:,2),'b')
            hold( app.UIAxes3, 'off' )
            legend(app.UIAxes3, 'Output','Noise')
            xlim(app.UIAxes3,[1, 1.35])
            
            plot(app.UIAxes4,app.freqs, abs(app.finput));
            plot(app.UIAxes5,app.freqs, abs(app.DataOut));
        end

        % Button pushed function: OutputButton
        function OutputButtonPushed(app, event)
       
            clear sound
            app.T=(length(app.DataIn)-1)/app.Fs; %calculates the duration of the input. May be useful depending the type of UI designed.
            sound(real(app.DataOut1),app.Fs) %plays the output
           
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Original')
            xlabel(app.UIAxes, 'Time')
            ylabel(app.UIAxes, 'Signal')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes2 = uiaxes(app.UIFigure);
            title(app.UIAxes2, 'Output')
            xlabel(app.UIAxes2, 'Time')
            ylabel(app.UIAxes2, 'Signal')
            zlabel(app.UIAxes2, 'Z')
            app.UIAxes3 = uiaxes(app.UIFigure);
            title(app.UIAxes3, 'Filtered vs Output')
            xlabel(app.UIAxes3, 'Time')
            ylabel(app.UIAxes3, 'Signal')
            zlabel(app.UIAxes3, 'Z')
            app.UIAxes4 = uiaxes(app.UIFigure);
            title(app.UIAxes4, 'Input Frequency Spectrum')
            xlabel(app.UIAxes4, 'Frequency (Hz)')
            ylabel(app.UIAxes4, 'Amplitude')
            zlabel(app.UIAxes4, 'Z')
            app.UIAxes5 = uiaxes(app.UIFigure);
            title(app.UIAxes5, 'Output Frequency Spectrum')
            xlabel(app.UIAxes5, 'Frequency (Hz)')
            ylabel(app.UIAxes5, 'Amplitude')
            zlabel(app.UIAxes5, 'Z')
            app.SoundFilesDropDownLabel = uilabel(app.UIFigure);
            app.SoundFilesDropDownLabel.Text = 'Sound Files';
            app.SoundFilesDropDown = uidropdown(app.UIFigure);
            app.SoundFilesDropDown.Items = {'Select a File', 'Armstrong', 'Asuman Pansuman', 'PhilipGlass'};
            app.SoundFilesDropDown.ValueChangedFcn = createCallbackFcn(app, @SoundFilesDropDownValueChanged, true);
            app.SoundFilesDropDown.Value = 'Select a File';
            app.OriginalButton = uibutton(app.UIFigure, 'push');
            app.OriginalButton.ButtonPushedFcn = createCallbackFcn(app, @OriginalButtonPushed, true);
            app.OriginalButton.Text = 'Original';
            app.OutputButton = uibutton(app.UIFigure, 'push');
            app.OutputButton.ButtonPushedFcn = createCallbackFcn(app, @OutputButtonPushed, true);
            app.OutputButton.Text = 'Output';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Sound_filter_with_fft

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end